classdef SolowApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SolowEconomicGrowthSimulatorLabel  matlab.ui.control.Label
        yStarLabel                  matlab.ui.control.Label
        kStarLabel                  matlab.ui.control.Label
        SimulationResultsPanel      matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        ModelParametersPanel        matlab.ui.container.Panel
        aField                      matlab.ui.control.NumericEditField
        CapitalshareEditFieldLabel  matlab.ui.control.Label
        dField                      matlab.ui.control.NumericEditField
        DepreciationEditFieldLabel  matlab.ui.control.Label
        nField                      matlab.ui.control.NumericEditField
        PopulationgrowthnEditFieldLabel  matlab.ui.control.Label
        sField                      matlab.ui.control.NumericEditField
        SavingsratesEditFieldLabel  matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            A = 1;
k0 = 0.1;
tspan = [0 200];

s     = app.sField.Value;
n     = app.nField.Value;
delta = app.dField.Value;
alpha = app.aField.Value;

[t,k] = ode45(@(t,k) s*A*k^alpha - (n+delta)*k, tspan, k0);
y = A * k.^alpha;

k_star = (s*A/(n+delta))^(1/(1-alpha));

cla(app.UIAxes);
hold(app.UIAxes,'on');
plot(app.UIAxes,t,k,'LineWidth',2);
plot(app.UIAxes,t,y,'--','LineWidth',2);
yline(app.UIAxes,k_star,'k:','k*');
legend(app.UIAxes,'Capital','Output','Steady state');
xlabel(app.UIAxes,'Time');
ylabel(app.UIAxes,'Level');
title(app.UIAxes,'Solow Growth Model');
hold(app.UIAxes,'off');
% Update result labels
app.kStarLabel.Text = sprintf('Steady-state capital:  k* = %.3f', k_star);
app.yStarLabel.Text = sprintf('Steady-state output:   y* = %.3f', A * k_star^alpha);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.RunButton = uibutton(app.ModelParametersPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the Solow model simulation'; ''};
            app.RunButton.Text = 'Run Simulation';
            app.SavingsratesEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.SavingsratesEditFieldLabel.Text = 'Savings rate (s)';
            app.sField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.sField.Limits = [0 1];
            app.sField.Value = 0.25;
            app.PopulationgrowthnEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.PopulationgrowthnEditFieldLabel.Text = 'Population growth (n)';
            app.nField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.nField.Limits = [0 0.1];
            app.nField.Value = 0.02;
            app.DepreciationEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.DepreciationEditFieldLabel.Text = 'Depreciation (δ)';
            app.dField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.dField.Limits = [0 0.1];
            app.dField.Value = 0.05;
            app.CapitalshareEditFieldLabel = uilabel(app.ModelParametersPanel);
            app.CapitalshareEditFieldLabel.Text = 'Capital share (α)';
            app.aField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.aField.Limits = [0 1];
            app.aField.Value = 0.33;
            app.SimulationResultsPanel = uipanel(app.UIFigure);
            app.SimulationResultsPanel.Title = 'Simulation Results';
            app.UIAxes = uiaxes(app.SimulationResultsPanel);
            title(app.UIAxes, 'Solow Growth Model')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Level')
            zlabel(app.UIAxes, 'Z')
            app.kStarLabel = uilabel(app.UIFigure);
            app.kStarLabel.Text = 'Steady-state capital k* =';
            app.yStarLabel = uilabel(app.UIFigure);
            app.yStarLabel.Text = 'Steady-state output y* =';
            app.SolowEconomicGrowthSimulatorLabel = uilabel(app.UIFigure);
            app.SolowEconomicGrowthSimulatorLabel.Text = 'Solow Economic Growth Simulator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolowApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end